classdef GUI_evaluation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MorphologyevaluationUIFigure    matlab.ui.Figure
        SampletypeDropDownLabel         matlab.ui.control.Label
        SampletypeDropDown              matlab.ui.control.DropDown
        corticalboneCheckBox            matlab.ui.control.CheckBox
        trabecularboneCheckBox          matlab.ui.control.CheckBox
        StartButton                     matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        shortreportCheckBox             matlab.ui.control.CheckBox
        fullreportCheckBox              matlab.ui.control.CheckBox
        outputasmatCheckBox             matlab.ui.control.CheckBox
        outputastifCheckBox             matlab.ui.control.CheckBox
        openreferencefileButton         matlab.ui.control.Button
        opendayXfilesButton             matlab.ui.control.Button
        savereportButton                matlab.ui.control.Button
        saveoutputButton                matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        savereportandoutputinsamefolderCheckBox  matlab.ui.control.CheckBox
        MorphologyevaluationLabel       matlab.ui.control.Label
        TextArea_5                      matlab.ui.control.TextArea
        TextArea_6                      matlab.ui.control.TextArea
        MorphologyelementparametersDropDownLabel  matlab.ui.control.Label
        MorphologyelementparametersDropDown  matlab.ui.control.DropDown
        TextArea_7                      matlab.ui.control.TextArea
        TextArea_8                      matlab.ui.control.TextArea
        corticalthresholdTextAreaLabel_2  matlab.ui.control.Label
        corticalthresholdTextAreaLabel_3  matlab.ui.control.Label
        greyvalueTextArea               matlab.ui.control.Label
        Acknowledgements                matlab.ui.control.Button
        TestButton                      matlab.ui.control.Button
        VoxelsizemppEditFieldLabel      matlab.ui.control.Label
        VoxelsizeumppEditField          matlab.ui.control.NumericEditField
        BonemainaxisDropDownLabel       matlab.ui.control.Label
        BonemainaxisDropDown            matlab.ui.control.DropDown
        RefLabel                        matlab.ui.control.Label
        DayXLabel                       matlab.ui.control.Label
        corticalthresholdEditFieldLabel  matlab.ui.control.Label
        corticalthresholdEditField      matlab.ui.control.NumericEditField
        corticalthresholdEditField_2    matlab.ui.control.NumericEditField
        trabecularthresholdEditFieldLabel  matlab.ui.control.Label
        trabecularthresholdEditField    matlab.ui.control.NumericEditField
        trabecularthresholdEditField_2  matlab.ui.control.NumericEditField
        SegmentationButton              matlab.ui.control.Button
        SegmentationSwitchLabel         matlab.ui.control.Label
        SegmentationSwitch              matlab.ui.control.RockerSwitch
        Lamp                            matlab.ui.control.Lamp
        FolderButton                    matlab.ui.control.Button
        VisualizationSwitchLabel        matlab.ui.control.Label
        VisualizationSwitch             matlab.ui.control.RockerSwitch
        StaticButton                    matlab.ui.control.Button
    end

    properties (Access = private)
        checkset = false;
        checkset2 = false;
        % paths plus files
        path % path for day 0 file
        reffile % reference file name (day 0)
        path2 % path for day X files
        files % day X file names
        inputfiles % a cell structure which contains all files to input to the eval function
        paths % a cell structure which contains all paths to input to the eval function
        results_path;
        days = cell(1,5); %a cell structure containing the assigned days of the input files
        % structural elements initialization
        R_1 = 1;
        R_2 = 5;
        R_3 = 20;
        R_4 = 40;
        disc_1;
        disc_2;
        disc_3;
        disc_4;
        morph; % a cell structure which contains all the structural elements
        % thresholds
        cort_thresh % cortical threshold
        trab_thresh % trabecular threshold
        % logical inputs
        is_mat = true; % output format as mat, if changed, value is changed later
        is_short = true; % output as short file, if changed, value is changed later
        folder_matlab = pwd;
    end

    methods (Access = private)
    
        function initialization_main(app)
            addpath('M:\BM\Hubert Taieb (M)\02 PhD\07 Matlab code\04 Custom general functions\Hubert functions\');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initialization_main(app)
        end

        % Value changed function: SampletypeDropDown
        function SampletypeDropDownValueChanged(app, event)
            if isequal(app.SampletypeDropDown.Value, app.SampletypeDropDown.Items{2})
                app.trabecularthresholdEditField.Enable = 'on';
                app.trabecularthresholdEditField_2.Enable = 'on';
                app.trabecularboneCheckBox.Enable = 'on';
                app.greyvalueTextArea.Enable = 'on';
            else
                app.trabecularthresholdEditField.Enable = 'off';
                app.trabecularthresholdEditField_2.Enable = 'off';
                app.trabecularboneCheckBox.Enable = 'off';
                app.trabecularboneCheckBox.Value = false;
                app.greyvalueTextArea.Enable = 'off';
            end
        end

        % Button pushed function: openreferencefileButton
        function openreferencefileButtonPushed(app, event)
            app.checkset = true;
       %   [app.reffile,app.path] = uigetfile({'*.tif;*.mat','Image Files (*.tif,*.mat)';'*.*','All Files (*.*)'},'Select the reference image file (Day 0) created by AMIRA');
       [app.reffile,app.path] = get_old_folder_position('tif','mat');
            
            if app.reffile == 0
                app.checkset = false;
                return % if one presses cancel, catch the error
            end
            reffile_name = strrep(app.reffile,'.tif','');
            reffile_name = strrep(reffile_name,'.mat','');
            app.TextArea_3.Value = reffile_name;
            if contains(app.reffile, 'd') == 1 && contains(app.reffile, 'day') == 0
                app.days{1} = str2double(extractAfter(reffile_name, 'd'));
                day0 = extractAfter(reffile_name, 'd');
            elseif contains(app.reffile, 'day') == 1
                app.days{1} = str2double(extractAfter(reffile_name, 'day'));
                day0 = extractAfter(reffile_name, 'day');
            end
            if contains(app.reffile, 'met') == 1
                app.SampletypeDropDown.Value = app.SampletypeDropDown.Items{2};
                app.trabecularthresholdEditFieldLabel.Enable = 'on';
                app.trabecularthresholdEditField.Enable = 'on';               
                app.trabecularthresholdEditField_2.Enable = 'on';
                app.trabecularboneCheckBox.Enable = 'on';
                app.greyvalueTextArea.Enable = 'on';
            end
            app.TextArea_8.Value = day0;
            app.inputfiles{1} = app.reffile;
            app.paths{1} = app.path;
            app.MorphologyevaluationUIFigure.Visible = 'off';
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end

        % Button pushed function: opendayXfilesButton
        function opendayXfilesButtonPushed(app, event)
            if app.checkset == true
                cd(app.path);
            end
            app.checkset2 = true;
%             [app.files,app.path2] = uigetfile({'*.tif;*.mat','Image Files (*.tif,*.mat)';'*.*','All Files (*.*)'},'Select the registered image files of !DAY X! created by AMIRA','Multiselect','on');
             [app.files,app.path2] = get_old_folder_position('tif','mat');
            if isempty(app.files) == 1
                app.checkset2 = false;
                return
            end
            if iscell(app.files) == 0 && isempty(app.files) ~= 0
                app.files = cellstr(app.files);
            end
            filename2 = strrep(app.files,'.tif','');
            filename2 = strrep(filename2,'.mat','');
            app.paths{2} = app.path2;
            if iscell(app.files) == 1
                for i = 1:length(app.files)
                    app.inputfiles{i+1} = app.files(i);
                    if contains(app.files(i), 'd') == 1 && contains(app.files(i), 'day') == 0
                        app.days{i+1} = str2double(extractAfter(filename2(i), 'd'))-app.days{1};
                        day = extractAfter(filename2, 'd');
                    elseif contains(app.files(i), 'day') == 1
                        app.days{i+1} = str2double(extractAfter(filename2(i), 'day'))-app.days{1};
                        day = extractAfter(filename2, 'day');
                    end
                end
            else
                app.inputfiles{2} = app.files;
                if contains(app.files, 'd') == 1 && contains(app.files, 'day') == 0
                    app.days{2} = str2double(extractAfter(filename2, 'd'))-app.days{1};
                    day = extractAfter(filename2, 'd');
                elseif contains(app.files, 'day') == 1
                    app.days{2} = str2double(extractAfter(filename2, 'day'))-app.days{1};
                    day = extractAfter(filename2, 'day');
                end
            end
            if app.checkset2 == true
                app.TextArea_5.Value = filename2;
                app.TextArea_7.Value = day;
            end
            app.MorphologyevaluationUIFigure.Visible = 'off';
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end

        % Value changed function: 
        % MorphologyelementparametersDropDown
        function MorphologyelementparametersDropDownValueChanged(app, event)
            Radius = {};
            if isequal(app.MorphologyelementparametersDropDown.Value,'young BALB/c')
                app.R_1 = 1;
                app.R_2 = 5;
                app.R_3 = 20;
                app.R_4 = 40;
            elseif isequal(app.MorphologyelementparametersDropDown.Value,'adult C57BL/6')
                app.R_1 = 1;
                app.R_2 = 2;
                app.R_3 = 3;
                app.R_4 = 6;
            elseif isequal(app.MorphologyelementparametersDropDown.Value,'SOST -/-')
                app.R_1 = 1;
                app.R_2 = 5;
                app.R_3 = 20;
                app.R_4 = 40;
            elseif isequal(app.MorphologyelementparametersDropDown.Value,'low bone mass')
                app.R_1 = 1;
                app.R_2 = 2;
                app.R_3 = 3;
                app.R_4 = 6;
            elseif isequal(app.MorphologyelementparametersDropDown.Value,'User input...')
                prompt = {'Enter R1','Enter R2','Enter R3','Enter R4'};
                dlg_title = 'Input of Structure Element Radii';
                defaultans = {'1','5','20','40'};
                num_lines = 1;
                Radius = inputdlg(prompt,dlg_title,num_lines,defaultans);
                if isempty(Radius) == 1
                    return
                else
                    app.R_1 = str2double(cell2mat(Radius(1)));
                    app.R_2 = str2double(cell2mat(Radius(2)));
                    app.R_3 = str2double(cell2mat(Radius(3)));
                    app.R_4 = str2double(cell2mat(Radius(4)));
                end
            end
            app.MorphologyevaluationUIFigure.Visible = 'off';
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end

        % Callback function
        function corticalthresholdTextAreaValueChanged(app, event)
            app.cort_thresh = app.corticalthresholdEditField.Value;
            app.cort_thresh = str2double(app.cort_thresh);
        end

        % Callback function
        function trabecularthresholdTextAreaValueChanged(app, event)
            app.trab_thresh = app.trabecularthresholdTextArea.Value;
            app.trab_thresh = str2double(app.trab_thresh);
        end

        % Value changed function: fullreportCheckBox
        function fullreportCheckBoxValueChanged(app, event)
            if  app.fullreportCheckBox.Value == true
                app.shortreportCheckBox.Value = false;
                app.shortreportCheckBox.Enable = 'off';
                app.is_short = false;
            else
                app.shortreportCheckBox.Value = true;
                app.shortreportCheckBox.Enable = 'on';
                app.is_short = true;
            end
        end

        % Value changed function: outputastifCheckBox
        function outputastifCheckBoxValueChanged(app, event)
            if app.outputastifCheckBox.Value == true
                app.outputasmatCheckBox.Value = false;
                app.is_mat = false;
            else
                app.outputasmatCheckBox.Value = true;
                app.is_mat = true;
            end
        end

        % Value changed function: 
        % savereportandoutputinsamefolderCheckBox
        function savereportandoutputinsamefolderCheckBoxValueChanged(app, event)
            if app.savereportandoutputinsamefolderCheckBox.Value == false
                app.saveoutputButton.Enable = 'on';
                app.TextArea_4.Enable = 'on';
            else
                app.saveoutputButton.Enable = 'off';
                app.TextArea_4.Enable = 'off';
            end
        end

        % Button pushed function: saveoutputButton
        function saveoutputButtonPushed(app, event)
            cd(app.path);
            path_VISres = uigetdir(app.path);
            if path_VISres == 0
                return
            end
            app.TextArea_4.Value = path_VISres;
            app.results_path{2} = path_VISres;
            app.MorphologyevaluationUIFigure.Visible = 'off';
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end

        % Value changed function: outputasmatCheckBox
        function outputasmatCheckBoxValueChanged(app, event)
            if app.outputasmatCheckBox.Value == true
                app.outputastifCheckBox.Value = false;
                app.is_mat = true;
            else
                app.outputastifCheckBox.Value = true;
                app.is_mat = false;
            end
        end

        % Button pushed function: savereportButton
        function savereportButtonPushed(app, event)
            if app.outputasmatCheckBox.Value == true
                app.outputastifCheckBox.Value = false;
                app.is_mat = true;
            else
                app.outputastifCheckBox.Value = true;
                app.is_mat = false;
            end
            cd(app.path);
            app.results_path{1} = uigetdir(app.path);
            if isempty(app.results_path) == 1
                return
            end
            if app.savereportandoutputinsamefolderCheckBox.Value == true
                app.results_path{2} = app.results_path{1};
            end
            app.TextArea_6.Value = app.results_path{1};
            app.MorphologyevaluationUIFigure.Visible = 'off';
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end

        % Value changed function: shortreportCheckBox
        function shortreportCheckBoxValueChanged(app, event)
            if  app.shortreportCheckBox.Value == false
                app.fullreportCheckBox.Value = true;
                app.is_short = false;
            else
                app.fullreportCheckBox.Value = false;
                app.is_short = true;
            end
            
        end

        % Value changed function: corticalboneCheckBox
        function corticalboneCheckBoxValueChanged(app, event)
            if app.trabecularboneCheckBox.Value == false
                app.corticalboneCheckBox.Value = true;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.morph{1} = app.R_1;
            app.morph{2} = app.R_2;
            app.morph{3} = app.R_3;
            app.morph{4} = app.R_4;
            cd(app.folder_matlab);
            if app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 0 && app.trabecularboneCheckBox.Value == false
                Both_Eval(app.inputfiles,app.paths,app.cort_thresh,app.morph,app.days,app.is_short,app.is_mat,app.results_path,...
                    app.VoxelsizeumppEditField.Value, app.BonemainaxisDropDown.Value,...
                    app.SegmentationSwitch.Value, app.VisualizationSwitch.Value, 'cort');
                ok = warndlg('Evaluation is done, you can choose new inputfiles and options!','Finished!');
            elseif app.checkset == true && app.checkset2 == true && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 0 && app.corticalboneCheckBox.Value == false
                Both_Eval(app.inputfiles,app.paths,app.trab_thresh,app.morph,app.days,app.is_short,app.is_mat,app.results_path,...
                    app.VoxelsizeumppEditField.Value, app.BonemainaxisDropDown.Value,...
                    app.SegmentationSwitch.Value, app.VisualizationSwitch.Value, 'trab');
                ok = warndlg('Evaluation is done, you can choose new inputfiles and options!','Finished!');
            elseif app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 0 && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 0
                Both_Eval(app.inputfiles,app.paths,app.cort_thresh,app.morph,app.days,app.is_short,app.is_mat,app.results_path,...
                    app.VoxelsizeumppEditField.Value, app.BonemainaxisDropDown.Value,...
                    app.SegmentationSwitch.Value, app.VisualizationSwitch.Value, 'cort');
                Both_Eval(app.inputfiles,app.paths,app.trab_thresh,app.morph,app.days,app.is_short,app.is_mat,app.results_path,...
                    app.VoxelsizeumppEditField.Value, app.BonemainaxisDropDown.Value,...
                    app.SegmentationSwitch.Value, app.VisualizationSwitch.Value, 'trab');
                ok = warndlg('Evaluation is done, you can choose new inputfiles and options!','Finished!');
            elseif app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 1
                no_thresh = warndlg('Please specify a cortical threshold!','Input missing');
            elseif app.checkset == true && app.checkset2 == true && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 1
                no_thresh = warndlg('Please specify a trabecular threshold!','Input missing');
            else
                no_input = warndlg('Please specify the right files!','Input missing');
            end
        end

        % Button pushed function: Acknowledgements
        function AcknowledgementsPushed(app, event)
            Aknowledge_1 = ['3D-Morphometry Evaluation V 1.1', newline, 'The following application uses a modified evaluation routine written in 2011 by:'];
            Aknowledge_2 = ['\bfAnette Birkhold', newline, '\rmbased on ideas by:'];
            Aknowledge_3 = ['\bfBettina M. Willie', newline, '\rmThe GUI for these routines was developed in 2019 by:', newline, '\bfMaximilian Rummler', newline, '\bfIsabela Vitienes'];
            Aknowledge_4 = ['\rmSpecial thanks goes to:', newline, '\bfHajar Razi, Laia Albiol Sanchez, Haisheng Yang, Erin Thomson,', newline,'Richard Weinkamer, Sara Checa, and Georg Duda'];
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            Acknowledgedialog = msgbox([Aknowledge_1, newline, Aknowledge_2, newline, Aknowledge_3, newline, Aknowledge_4],'Aknowledgements','help',CreateStruct);
            
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.morph{1} = app.R_1;
            app.morph{2} = app.R_2;
            app.morph{3} = app.R_3;
            app.morph{4} = app.R_4;
            cd(app.folder_matlab);
            if app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 0 && app.trabecularboneCheckBox.Value == false
                seg_test(app.inputfiles,app.paths,app.cort_thresh,app.morph,app.BonemainaxisDropDown.Value);
            elseif app.checkset == true && app.checkset2 == true && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 0 && app.corticalboneCheckBox.Value == false
                seg_test(app.inputfiles,app.paths,app.trab_thresh,app.morph,app.BonemainaxisDropDown.Value);
            elseif app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 0 && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 0
                seg_test(app.inputfiles,app.paths,app.cort_thresh,app.morph,app.BonemainaxisDropDown.Value);
            elseif app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 1
                no_thresh = warndlg('Please specify a cortical threshold!','Input missing');
            else
                no_input = warndlg('Please specify the right files!','Input missing');
            end
        end

        % Value changed function: VoxelsizeumppEditField
        function VoxelsizeumppEditFieldValueChanged(app, event)
            value = app.VoxelsizeumppEditField.Value;
            
        end

        % Value changed function: corticalthresholdEditField
        function corticalthresholdEditFieldValueChanged(app, event)
            app.cort_thresh(1,1) = double(app.corticalthresholdEditField.Value);
            app.cort_thresh(2,1) = double(app.corticalthresholdEditField.Value);
            app.corticalthresholdEditField_2.Value = app.cort_thresh(1,1);
        end

        % Value changed function: corticalthresholdEditField_2
        function corticalthresholdEditField_2ValueChanged(app, event)
            app.cort_thresh(2,1) = app.corticalthresholdEditField_2.Value;
            
        end

        % Value changed function: trabecularthresholdEditField
        function trabecularthresholdEditFieldValueChanged(app, event)
             app.trab_thresh(1,1) = double(app.trabecularthresholdEditField.Value);
            app.trab_thresh(2,1) = double(app.trabecularthresholdEditField.Value);
            app.trabecularthresholdEditField_2.Value = app.trab_thresh(1,1);
        end

        % Value changed function: trabecularthresholdEditField_2
        function trabecularthresholdEditField_2ValueChanged(app, event)
             app.trab_thresh(2,1) = app.trabecularthresholdEditField_2.Value;
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
                        app.morph{1} = app.R_1;
            app.morph{2} = app.R_2;
            app.morph{3} = app.R_3;
            app.morph{4} = app.R_4;
            cd(app.folder_matlab);
            if app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 0 && app.trabecularboneCheckBox.Value == false
                seg_only(app.inputfiles,app.paths,app.cort_thresh,app.morph,app.BonemainaxisDropDown.Value);
            elseif app.checkset == true && app.checkset2 == true && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 0 && app.corticalboneCheckBox.Value == false
                seg_only(app.inputfiles,app.paths,app.trab_thresh,app.morph,app.BonemainaxisDropDown.Value);
            elseif app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 0 && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 0
                seg_only(app.inputfiles,app.paths,app.cort_thresh,app.morph,app.BonemainaxisDropDown.Value);
            elseif app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 1
                no_thresh = warndlg('Please specify a cortical threshold!','Input missing');
            else
                no_input = warndlg('Please specify the right files!','Input missing');
            end
        end

        % Value changed function: SegmentationSwitch
        function SegmentationSwitchValueChanged(app, event)
            value = app.SegmentationSwitch.Value;
            if strcmp(value,'On')
                app.Lamp.Color = [0 1 0];
            else
                app.Lamp.Color = [1 0 0];
            end
        end

        % Value changed function: trabecularboneCheckBox
        function trabecularboneCheckBoxValueChanged(app, event)
            value = app.trabecularboneCheckBox.Value;
            if strcmp(app.SegmentationSwitch.Value,'Off')
                app.trabecularthresholdEditField.Value = 1;
                app.trabecularthresholdEditField_2.Value = 1;
            end
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
           explorer(char(app.TextArea_6.Value));
        end

        % Button pushed function: StaticButton
        function StaticButtonPushed(app, event)
            app.morph{1} = app.R_1;
            app.morph{2} = app.R_2;
            app.morph{3} = app.R_3;
            app.morph{4} = app.R_4;
            static(app.inputfiles,app.paths,app.cort_thresh,app.morph,app.BonemainaxisDropDown.Value, app.VoxelsizeumppEditField.Value, app.results_path);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MorphologyevaluationUIFigure = uifigure('Visible', 'off');
            app.MorphologyevaluationUIFigure.Name = 'Morphology evaluation';
            app.SampletypeDropDownLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.SampletypeDropDownLabel.Text = 'Sample type';
            app.SampletypeDropDown = uidropdown(app.MorphologyevaluationUIFigure);
            app.SampletypeDropDown.Items = {'Diaphysis', 'Metaphysis'};
            app.SampletypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SampletypeDropDownValueChanged, true);
            app.SampletypeDropDown.Value = 'Diaphysis';
            app.corticalboneCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.corticalboneCheckBox.ValueChangedFcn = createCallbackFcn(app, @corticalboneCheckBoxValueChanged, true);
            app.corticalboneCheckBox.Text = 'cortical bone';
            app.corticalboneCheckBox.Value = true;
            app.trabecularboneCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.trabecularboneCheckBox.ValueChangedFcn = createCallbackFcn(app, @trabecularboneCheckBoxValueChanged, true);
            app.trabecularboneCheckBox.Enable = 'off';
            app.trabecularboneCheckBox.Text = 'trabecular bone';
            app.StartButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.shortreportCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.shortreportCheckBox.ValueChangedFcn = createCallbackFcn(app, @shortreportCheckBoxValueChanged, true);
            app.shortreportCheckBox.Text = 'short report';
            app.shortreportCheckBox.Value = true;
            app.fullreportCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.fullreportCheckBox.ValueChangedFcn = createCallbackFcn(app, @fullreportCheckBoxValueChanged, true);
            app.fullreportCheckBox.Text = 'full report';
            app.outputasmatCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.outputasmatCheckBox.ValueChangedFcn = createCallbackFcn(app, @outputasmatCheckBoxValueChanged, true);
            app.outputasmatCheckBox.Text = 'output as .mat';
            app.outputastifCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.outputastifCheckBox.ValueChangedFcn = createCallbackFcn(app, @outputastifCheckBoxValueChanged, true);
            app.outputastifCheckBox.Text = 'output as .tif';
            app.outputastifCheckBox.Value = true;
            app.openreferencefileButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.openreferencefileButton.ButtonPushedFcn = createCallbackFcn(app, @openreferencefileButtonPushed, true);
            app.openreferencefileButton.Text = 'open reference file';
            app.opendayXfilesButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.opendayXfilesButton.ButtonPushedFcn = createCallbackFcn(app, @opendayXfilesButtonPushed, true);
            app.opendayXfilesButton.Text = 'open day X files';
            app.savereportButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.savereportButton.ButtonPushedFcn = createCallbackFcn(app, @savereportButtonPushed, true);
            app.savereportButton.Text = 'save report';
            app.saveoutputButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.saveoutputButton.ButtonPushedFcn = createCallbackFcn(app, @saveoutputButtonPushed, true);
            app.saveoutputButton.Enable = 'off';
            app.saveoutputButton.Text = 'save output';
            app.TextArea_3 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_4 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Enable = 'off';
            app.savereportandoutputinsamefolderCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.savereportandoutputinsamefolderCheckBox.ValueChangedFcn = createCallbackFcn(app, @savereportandoutputinsamefolderCheckBoxValueChanged, true);
            app.savereportandoutputinsamefolderCheckBox.Text = 'save report and output in same folder';
            app.savereportandoutputinsamefolderCheckBox.Value = true;
            app.MorphologyevaluationLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.MorphologyevaluationLabel.Text = 'Morphology evaluation';
            app.TextArea_5 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_5.Editable = 'off';
            app.TextArea_6 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_6.Editable = 'off';
            app.MorphologyelementparametersDropDownLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.MorphologyelementparametersDropDownLabel.Text = 'Morphology element parameters';
            app.MorphologyelementparametersDropDown = uidropdown(app.MorphologyevaluationUIFigure);
            app.MorphologyelementparametersDropDown.Items = {'young BALB/c', 'adult C57BL/6', 'SOST -/-', 'low bone mass', 'User input...'};
            app.MorphologyelementparametersDropDown.ValueChangedFcn = createCallbackFcn(app, @MorphologyelementparametersDropDownValueChanged, true);
            app.MorphologyelementparametersDropDown.Value = 'young BALB/c';
            app.TextArea_7 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_7.Editable = 'off';
            app.TextArea_8 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_8.Editable = 'off';
            app.corticalthresholdTextAreaLabel_2 = uilabel(app.MorphologyevaluationUIFigure);
            app.corticalthresholdTextAreaLabel_2.Text = 'Day';
            app.corticalthresholdTextAreaLabel_3 = uilabel(app.MorphologyevaluationUIFigure);
            app.corticalthresholdTextAreaLabel_3.Text = 'in greyvalues';
            app.greyvalueTextArea = uilabel(app.MorphologyevaluationUIFigure);
            app.greyvalueTextArea.Enable = 'off';
            app.greyvalueTextArea.Text = 'in greyvalues';
            app.Acknowledgements = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.Acknowledgements.ButtonPushedFcn = createCallbackFcn(app, @AcknowledgementsPushed, true);
            app.Acknowledgements.Text = '?';
            app.TestButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.VoxelsizemppEditFieldLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.VoxelsizemppEditFieldLabel.Text = 'Voxel size (µm/pp)';
            app.VoxelsizeumppEditField = uieditfield(app.MorphologyevaluationUIFigure, 'numeric');
            app.VoxelsizeumppEditField.Limits = [0 100];
            app.VoxelsizeumppEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelsizeumppEditFieldValueChanged, true);
            app.VoxelsizeumppEditField.Value = 8.5;
            app.BonemainaxisDropDownLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.BonemainaxisDropDownLabel.Text = 'Bone main axis';
            app.BonemainaxisDropDown = uidropdown(app.MorphologyevaluationUIFigure);
            app.BonemainaxisDropDown.Items = {'x', 'y', 'z'};
            app.BonemainaxisDropDown.Value = 'y';
            app.RefLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.RefLabel.Text = 'Ref';
            app.DayXLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.DayXLabel.Text = 'Day X';
            app.corticalthresholdEditFieldLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.corticalthresholdEditFieldLabel.Text = 'cortical threshold';
            app.corticalthresholdEditField = uieditfield(app.MorphologyevaluationUIFigure, 'numeric');
            app.corticalthresholdEditField.Limits = [0 60000];
            app.corticalthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @corticalthresholdEditFieldValueChanged, true);
            app.corticalthresholdEditField_2 = uieditfield(app.MorphologyevaluationUIFigure, 'numeric');
            app.corticalthresholdEditField_2.Limits = [0 60000];
            app.corticalthresholdEditField_2.ValueChangedFcn = createCallbackFcn(app, @corticalthresholdEditField_2ValueChanged, true);
            app.trabecularthresholdEditFieldLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.trabecularthresholdEditFieldLabel.Enable = 'off';
            app.trabecularthresholdEditFieldLabel.Text = 'trabecular threshold';
            app.trabecularthresholdEditField = uieditfield(app.MorphologyevaluationUIFigure, 'numeric');
            app.trabecularthresholdEditField.Limits = [0 60000];
            app.trabecularthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @trabecularthresholdEditFieldValueChanged, true);
            app.trabecularthresholdEditField.Enable = 'off';
            app.trabecularthresholdEditField_2 = uieditfield(app.MorphologyevaluationUIFigure, 'numeric');
            app.trabecularthresholdEditField_2.Limits = [0 60000];
            app.trabecularthresholdEditField_2.ValueChangedFcn = createCallbackFcn(app, @trabecularthresholdEditField_2ValueChanged, true);
            app.trabecularthresholdEditField_2.Enable = 'off';
            app.SegmentationButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            app.SegmentationSwitchLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.SegmentationSwitchLabel.Text = 'Segmentation';
            app.SegmentationSwitch = uiswitch(app.MorphologyevaluationUIFigure, 'rocker');
            app.SegmentationSwitch.ValueChangedFcn = createCallbackFcn(app, @SegmentationSwitchValueChanged, true);
            app.SegmentationSwitch.Value = 'On';
            app.Lamp = uilamp(app.MorphologyevaluationUIFigure);
            app.FolderButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.VisualizationSwitchLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.VisualizationSwitchLabel.Text = 'Visualization';
            app.VisualizationSwitch = uiswitch(app.MorphologyevaluationUIFigure, 'rocker');
            app.VisualizationSwitch.Items = {'Short', 'Full'};
            app.VisualizationSwitch.Value = 'Short';
            app.StaticButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.StaticButton.ButtonPushedFcn = createCallbackFcn(app, @StaticButtonPushed, true);
            app.StaticButton.Text = 'Static';
            % Show the figure after all components are created
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_evaluation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MorphologyevaluationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MorphologyevaluationUIFigure)
        end
    end
end