classdef GUI_evaluation_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MorphologyevaluationUIFigure  matlab.ui.Figure
        ThistooliscoveredundertheGNUGeneralPublicLicenseLabel  matlab.ui.control.Label
        BirkholdetalBiomaterials2014Label  matlab.ui.control.Label
        BirkholdetalBone2014Label     matlab.ui.control.Label
        IfusingforcorticalboneanalysispleasereferenceLabel  matlab.ui.control.Label
        OptimizedformousetibiaeLabel  matlab.ui.control.Label
        ResolutionEditFieldLabel_2    matlab.ui.control.Label
        ResolutionEditField           matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel      matlab.ui.control.Label
        TestButton                    matlab.ui.control.Button
        Acknowledgements              matlab.ui.control.Button
        greyvalueTextArea             matlab.ui.control.Label
        corticalthresholdTextAreaLabel_3  matlab.ui.control.Label
        corticalthresholdTextAreaLabel_2  matlab.ui.control.Label
        TextArea_8                    matlab.ui.control.TextArea
        TextArea_7                    matlab.ui.control.TextArea
        MorphologyelementparametersDropDown  matlab.ui.control.DropDown
        MorphologyelementparametersDropDownLabel  matlab.ui.control.Label
        TextArea_6                    matlab.ui.control.TextArea
        TextArea_5                    matlab.ui.control.TextArea
        MorphologyevaluationLabel     matlab.ui.control.Label
        savereportandoutputinsamefolderCheckBox  matlab.ui.control.CheckBox
        TextArea_4                    matlab.ui.control.TextArea
        TextArea_3                    matlab.ui.control.TextArea
        saveoutputButton              matlab.ui.control.Button
        savereportButton              matlab.ui.control.Button
        opendayXfilesButton           matlab.ui.control.Button
        openreferencefileButton       matlab.ui.control.Button
        outputastifCheckBox           matlab.ui.control.CheckBox
        outputasmatCheckBox           matlab.ui.control.CheckBox
        trabecularthresholdsTextArea  matlab.ui.control.TextArea
        trabecularthresholdsTextAreaLabel  matlab.ui.control.Label
        fullreportCheckBox            matlab.ui.control.CheckBox
        shortreportCheckBox           matlab.ui.control.CheckBox
        ExitButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        trabecularboneCheckBox        matlab.ui.control.CheckBox
        corticalboneCheckBox          matlab.ui.control.CheckBox
        corticalthresholdsTextArea    matlab.ui.control.TextArea
        corticalthresholdsTextAreaLabel  matlab.ui.control.Label
        SampletypeDropDown            matlab.ui.control.DropDown
        SampletypeDropDownLabel       matlab.ui.control.Label
    end

    properties (Access = private)
        checkset = false;
        checkset2 = false;
        % paths plus files
        path % path for day 0 file
        reffile % reference file name (day 0)
        path2 % path for day X files
        files % day X file names
        inputfiles % a cell structure which contains all files to input to the eval function
        paths % a cell structure which contains all paths to input to the eval function
        results_path;
        days = cell(1,5); %a cell structure containing the assigned days of the input files
        % structural elements initialization
        R_1 = 1;
        R_2 = 5;
        R_3 = 20;
        R_4 = 40;
        disc_1;
        disc_2;
        disc_3;
        disc_4;
        morph; % a cell structure which contains all the structural elements
        % thresholds
        cort_thresh % cortical threshold
        trab_thresh % trabecular threshold
        % logical inputs
        is_mat = true; % output format as mat, if changed, value is changed later
        is_short = true; % output as short file, if changed, value is changed later
        folder_matlab = pwd;
        reso % image resolution in um
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SampletypeDropDown
        function SampletypeDropDownValueChanged(app, event)
            if isequal(app.SampletypeDropDown.Value, app.SampletypeDropDown.Items{2}) % if metaph selected
                app.trabecularthresholdsTextAreaLabel.Enable = 'on';
                app.trabecularthresholdsTextArea.Enable = 'on';
                app.trabecularboneCheckBox.Enable = 'on';
                app.greyvalueTextArea.Enable = 'on';
            else
                app.trabecularthresholdsTextAreaLabel.Enable = 'off';
                app.trabecularthresholdsTextArea.Enable = 'off';
                app.trabecularthresholdCheckBox.Value = false;
                app.trabecularboneCheckBox.Enable = 'off';
                app.greyvalueTextArea.Enable = 'off';
            end
        end

        % Button pushed function: openreferencefileButton
        function openreferencefileButtonPushed(app, event)
            app.reffile = {};
            app.checkset = true;
            [app.reffile,app.path] = uigetfile({'*.tif;*.mat','Image Files (*.tif,*.mat)';'*.*','All Files (*.*)'},'Select the reference image file (Day 0) created by AMIRA');
            if app.reffile == 0
                app.checkset = false;
                return % if one presses cancel, catch the error
            end
            reffile_name = strrep(app.reffile,'.tif','');
            reffile_name = strrep(reffile_name,'.mat','');
            app.TextArea_3.Value = reffile_name;
            if contains(app.reffile, 'd') == 1 && contains(app.reffile, 'day') == 0
                app.days{1} = str2double(extractAfter(reffile_name, 'd'));
                day0 = extractAfter(reffile_name, 'd');
            elseif contains(app.reffile, 'day') == 1
                app.days{1} = str2double(extractAfter(reffile_name, 'day'));
                day0 = extractAfter(reffile_name, 'day');
            end
            if contains(app.reffile, 'met') == 1
                app.SampletypeDropDown.Value = app.SampletypeDropDown.Items{2};
                app.trabecularthresholdsTextAreaLabel.Enable = 'on';
                app.trabecularthresholdsTextArea.Enable = 'on';
                app.trabecularboneCheckBox.Enable = 'on';
                app.greyvalueTextArea.Enable = 'on';
            end
            app.TextArea_8.Value = day0;
            app.inputfiles{1} = app.reffile;
            app.paths{1} = app.path;
            app.MorphologyevaluationUIFigure.Visible = 'off';
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end

        % Button pushed function: opendayXfilesButton
        function opendayXfilesButtonPushed(app, event)
            app.files = {};
            app.path2 = {};
            if app.checkset == true
                cd(app.path);
            end
            app.checkset2 = true;
            [app.files,app.path2] = uigetfile({'*.tif;*.mat','Image Files (*.tif,*.mat)';'*.*','All Files (*.*)'},'Select the registered image files of !DAY X! created by AMIRA','Multiselect','on');
            if isempty(app.files) == 1
                app.checkset2 = false;
                return
            end
            if iscell(app.files) == 0 && isempty(app.files) ~= 0
                app.files = cellstr(app.files);
            end
            filename2 = strrep(app.files,'.tif','');
            filename2 = strrep(filename2,'.mat','');
            app.paths{2} = app.path2;
            if iscell(app.files) == 1
                for i = 1:length(app.files)
                    app.inputfiles{i+1} = app.files{i};
                    if contains(app.files(i), 'd') == 1 && contains(app.files(i), 'day') == 0
                        app.days{i+1} = str2double(extractAfter(filename2(i), 'd'))-app.days{1};
                        day = extractAfter(filename2, 'd');
                    elseif contains(app.files(i), 'day') == 1
                        app.days{i+1} = str2double(extractAfter(filename2(i), 'day'))-app.days{1};
                        day = extractAfter(filename2, 'day');
                    end
                end
            else
                app.inputfiles{2} = app.files;
                if contains(app.files, 'd') == 1 && contains(app.files, 'day') == 0
                    app.days{2} = str2double(extractAfter(filename2, 'd'))-app.days{1};
                    day = extractAfter(filename2, 'd');
                elseif contains(app.files, 'day') == 1
                    app.days{2} = str2double(extractAfter(filename2, 'day'))-app.days{1};
                    day = extractAfter(filename2, 'day');
                end
            end
            if app.checkset2 == true
                app.TextArea_5.Value = filename2;
                app.TextArea_7.Value = day;
            end
            app.MorphologyevaluationUIFigure.Visible = 'off';
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end

        % Value changed function: MorphologyelementparametersDropDown
        function MorphologyelementparametersDropDownValueChanged(app, event)
            Radius = {};
            if isequal(app.MorphologyelementparametersDropDown.Value,'young BALB/c')
                app.R_1 = 1;
                app.R_2 = 5;
                app.R_3 = 20;
                app.R_4 = 40;
            elseif isequal(app.MorphologyelementparametersDropDown.Value,'adult C57BL/6')
                app.R_1 = 1;
                app.R_2 = 2;
                app.R_3 = 3;
                app.R_4 = 6;
            elseif isequal(app.MorphologyelementparametersDropDown.Value,'SOST -/-')
                app.R_1 = 1;
                app.R_2 = 5;
                app.R_3 = 20;
                app.R_4 = 40;
            elseif isequal(app.MorphologyelementparametersDropDown.Value,'low bone mass')
                app.R_1 = 1;
                app.R_2 = 2;
                app.R_3 = 3;
                app.R_4 = 6;
            elseif isequal(app.MorphologyelementparametersDropDown.Value,'User input...')
                prompt = {'Enter R1','Enter R2','Enter R3','Enter R4'};
                dlg_title = 'Input of Structure Element Radii';
                defaultans = {'1','5','20','40'};
                num_lines = 1;
                Radius = inputdlg(prompt,dlg_title,num_lines,defaultans);
                if isempty(Radius) == 1
                    return
                else
                    app.R_1 = str2double(cell2mat(Radius(1)));
                    app.R_2 = str2double(cell2mat(Radius(2)));
                    app.R_3 = str2double(cell2mat(Radius(3)));
                    app.R_4 = str2double(cell2mat(Radius(4)));
                end
            end
            app.MorphologyevaluationUIFigure.Visible = 'off';
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end

        % Value changed function: corticalthresholdsTextArea
        function corticalthresholdsTextAreaValueChanged(app, event)
            app.cort_thresh = app.corticalthresholdsTextArea.Value;
            app.cort_thresh = char(app.cort_thresh);
            app.cort_thresh = str2num(app.cort_thresh);
        end

        % Value changed function: trabecularthresholdsTextArea
        function trabecularthresholdsTextAreaValueChanged(app, event)
            app.trab_thresh = app.trabecularthresholdsTextArea.Value;
            app.trab_thresh = char(app.trab_thresh);
            app.trab_thresh = str2num(app.trab_thresh);
        end

        % Value changed function: fullreportCheckBox
        function fullreportCheckBoxValueChanged(app, event)
            if  app.fullreportCheckBox.Value == true
                app.shortreportCheckBox.Value = false;
                app.shortreportCheckBox.Enable = 'off';
                app.is_short = false;
            else
                app.shortreportCheckBox.Value = true;
                app.shortreportCheckBox.Enable = 'on';
                app.is_short = true;
            end
        end

        % Value changed function: outputastifCheckBox
        function outputastifCheckBoxValueChanged(app, event)
            if app.outputastifCheckBox.Value == true
                app.outputasmatCheckBox.Value = false;
                app.is_mat = false;
            else
                app.outputasmatCheckBox.Value = true;
                app.is_mat = true;
            end
        end

        % Value changed function: savereportandoutputinsamefolderCheckBox
        function savereportandoutputinsamefolderCheckBoxValueChanged(app, event)
            if app.savereportandoutputinsamefolderCheckBox.Value == false
                app.saveoutputButton.Enable = 'on';
                app.TextArea_4.Enable = 'on';
            else
                app.saveoutputButton.Enable = 'off';
                app.TextArea_4.Enable = 'off';
            end
        end

        % Button pushed function: saveoutputButton
        function saveoutputButtonPushed(app, event)
            cd(app.path);
            path_VISres = uigetdir(app.path);
            if path_VISres == 0
                return
            end
            app.TextArea_4.Value = path_VISres;
            app.results_path{2} = path_VISres;
            app.MorphologyevaluationUIFigure.Visible = 'off';
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end

        % Value changed function: outputasmatCheckBox
        function outputasmatCheckBoxValueChanged(app, event)
            if app.outputasmatCheckBox.Value == true
                app.outputastifCheckBox.Value = false;
                app.is_mat = true;
            else
                app.outputastifCheckBox.Value = true;
                app.is_mat = false;
            end
        end

        % Button pushed function: savereportButton
        function savereportButtonPushed(app, event)
            cd(app.path);
            app.results_path{1} = uigetdir(app.path);
            if isempty(app.results_path) == 1
                return
            end
            if app.savereportandoutputinsamefolderCheckBox.Value == true
                app.results_path{2} = app.results_path{1};
            end
            app.TextArea_6.Value = app.results_path{1};
            app.MorphologyevaluationUIFigure.Visible = 'off';
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end

        % Value changed function: shortreportCheckBox
        function shortreportCheckBoxValueChanged(app, event)
            if  app.shortreportCheckBox.Value == false
                app.fullreportCheckBox.Value = true;
                app.is_short = false;
            else
                app.fullreportCheckBox.Value = false;
                app.is_short = true;
            end


        end

        % Value changed function: corticalboneCheckBox
        function corticalboneCheckBoxValueChanged(app, event)
            if app.trabecularboneCheckBox.Value == false
                app.corticalboneCheckBox.Value = true;
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.morph{1} = app.R_1;
            app.morph{2} = app.R_2;
            app.morph{3} = app.R_3;
            app.morph{4} = app.R_4;
            cd(app.folder_matlab);
            if app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 0 && app.trabecularboneCheckBox.Value == false
                cortEval_2(app.inputfiles,app.paths,app.cort_thresh,app.morph,app.days,app.is_short,app.is_mat,app.results_path, app.reso);
                ok = warndlg('Evaluation is done, you can choose new inputfiles and options!','Finished!');
            elseif app.checkset == true && app.checkset2 == true && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 0 && app.corticalboneCheckBox.Value == false
                trabEval_2(app.inputfiles,app.paths,app.trab_thresh,app.morph,app.days,app.is_short,app.is_mat,app.results_path, app.reso);
                ok = warndlg('Evaluation is done, you can choose new inputfiles and options!','Finished!');
            elseif app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 0 && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 0
                cortEval_2(app.inputfiles,app.paths,app.cort_thresh,app.morph,app.days,app.is_short,app.is_mat,app.results_path, app.reso);
                trabEval_2(app.inputfiles,app.paths,app.trab_thresh,app.morph,app.days,app.is_short,app.is_mat,app.results_path, app.reso);
                ok = warndlg('Evaluation is done, you can choose new inputfiles and options!','Finished!');
            elseif app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 1
                no_thresh = warndlg('Please specify a cortical threshold!','Input missing');
            elseif app.checkset == true && app.checkset2 == true && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 1
                no_thresh = warndlg('Please specify a trabecular threshold!','Input missing');
            else
                no_input = warndlg('Please specify the right files!','Input missing');
            end
        end

        % Button pushed function: Acknowledgements
        function AcknowledgementsPushed(app, event)
            Aknowledge_1 = ['3D-Morphometry Evaluation V 1.1', newline, 'The following application uses a modified evaluation routine conceptualized by:'];
            Aknowledge_2 = ['\bfBettina M. Willie and Annette Birkhold', newline, '\rmand Originally written in 2011 by:'];
            Aknowledge_3 = ['\bfAnnette Birkhold', newline, '\rmThe GUI for these routines was developed in 2019 by:', newline, '\bfMaximilian Rummler and Isabela Vitienes'];
            Aknowledge_4 = ['\rmSpecial thanks goes to:', newline, '\bfHajar Razi, Laia Albiol Sanchez, Haisheng Yang, Erin Thomson,', newline,'Richard Weinkamer, Sara Checa, and Georg Duda'];
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            Acknowledgedialog = msgbox([Aknowledge_1, newline, Aknowledge_2, newline, Aknowledge_3, newline, Aknowledge_4],'Aknowledgements','help',CreateStruct);
            
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.morph{1} = app.R_1;
            app.morph{2} = app.R_2;
            app.morph{3} = app.R_3;
            app.morph{4} = app.R_4;
            cd(app.folder_matlab);
            if app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 0 && app.trabecularboneCheckBox.Value == false
                seg_test_2(app.inputfiles,app.paths,app.cort_thresh,app.morph);
            elseif app.checkset == true && app.checkset2 == true && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 0 && app.corticalboneCheckBox.Value == false
                seg_test_2(app.inputfiles,app.paths,app.trab_thresh,app.morph);
            elseif app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 0 && app.trabecularboneCheckBox.Value == true && isempty(app.trab_thresh) == 0
                seg_test_2(app.inputfiles,app.paths,app.cort_thresh,app.morph);
            elseif app.checkset == true && app.checkset2 == true && app.corticalboneCheckBox.Value == true && isempty(app.cort_thresh) == 1
                no_thresh = warndlg('Please specify a cortical threshold!','Input missing');
            else
                no_input = warndlg('Please specify the right files!','Input missing');
            end
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            app.reso = app.ResolutionEditField.Value;
            app.reso = double(app.reso);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MorphologyevaluationUIFigure = uifigure('Visible', 'off');
            app.MorphologyevaluationUIFigure.Name = 'Morphology evaluation';
            app.SampletypeDropDownLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.SampletypeDropDownLabel.Text = 'Sample type';
            app.SampletypeDropDown = uidropdown(app.MorphologyevaluationUIFigure);
            app.SampletypeDropDown.Items = {'Diaphysis', 'Metaphysis'};
            app.SampletypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SampletypeDropDownValueChanged, true);
            app.SampletypeDropDown.Value = 'Diaphysis';
            app.corticalthresholdsTextAreaLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.corticalthresholdsTextAreaLabel.Text = 'cortical thresholds';
            app.corticalthresholdsTextArea = uitextarea(app.MorphologyevaluationUIFigure);
            app.corticalthresholdsTextArea.ValueChangedFcn = createCallbackFcn(app, @corticalthresholdsTextAreaValueChanged, true);
            app.corticalboneCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.corticalboneCheckBox.ValueChangedFcn = createCallbackFcn(app, @corticalboneCheckBoxValueChanged, true);
            app.corticalboneCheckBox.Text = 'cortical bone';
            app.corticalboneCheckBox.Value = true;
            app.trabecularboneCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.trabecularboneCheckBox.Enable = 'off';
            app.trabecularboneCheckBox.Text = 'trabecular bone';
            app.StartButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.shortreportCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.shortreportCheckBox.ValueChangedFcn = createCallbackFcn(app, @shortreportCheckBoxValueChanged, true);
            app.shortreportCheckBox.Text = 'short report';
            app.shortreportCheckBox.Value = true;
            app.fullreportCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.fullreportCheckBox.ValueChangedFcn = createCallbackFcn(app, @fullreportCheckBoxValueChanged, true);
            app.fullreportCheckBox.Text = 'full report';
            app.trabecularthresholdsTextAreaLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.trabecularthresholdsTextAreaLabel.Enable = 'off';
            app.trabecularthresholdsTextAreaLabel.Text = 'trabecular thresholds';
            app.trabecularthresholdsTextArea = uitextarea(app.MorphologyevaluationUIFigure);
            app.trabecularthresholdsTextArea.ValueChangedFcn = createCallbackFcn(app, @trabecularthresholdsTextAreaValueChanged, true);
            app.trabecularthresholdsTextArea.Enable = 'off';
            app.outputasmatCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.outputasmatCheckBox.ValueChangedFcn = createCallbackFcn(app, @outputasmatCheckBoxValueChanged, true);
            app.outputasmatCheckBox.Text = 'output as .mat';
            app.outputasmatCheckBox.Value = true;
            app.outputastifCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.outputastifCheckBox.ValueChangedFcn = createCallbackFcn(app, @outputastifCheckBoxValueChanged, true);
            app.outputastifCheckBox.Text = 'output as .tif';
            app.openreferencefileButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.openreferencefileButton.ButtonPushedFcn = createCallbackFcn(app, @openreferencefileButtonPushed, true);
            app.openreferencefileButton.Text = 'open reference file';
            app.opendayXfilesButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.opendayXfilesButton.ButtonPushedFcn = createCallbackFcn(app, @opendayXfilesButtonPushed, true);
            app.opendayXfilesButton.Text = 'open day X files';
            app.savereportButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.savereportButton.ButtonPushedFcn = createCallbackFcn(app, @savereportButtonPushed, true);
            app.savereportButton.Text = 'save report';
            app.saveoutputButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.saveoutputButton.ButtonPushedFcn = createCallbackFcn(app, @saveoutputButtonPushed, true);
            app.saveoutputButton.Enable = 'off';
            app.saveoutputButton.Text = 'save output';
            app.TextArea_3 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_4 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Enable = 'off';
            app.savereportandoutputinsamefolderCheckBox = uicheckbox(app.MorphologyevaluationUIFigure);
            app.savereportandoutputinsamefolderCheckBox.ValueChangedFcn = createCallbackFcn(app, @savereportandoutputinsamefolderCheckBoxValueChanged, true);
            app.savereportandoutputinsamefolderCheckBox.Text = 'save report and output in same folder';
            app.savereportandoutputinsamefolderCheckBox.Value = true;
            app.MorphologyevaluationLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.MorphologyevaluationLabel.Text = 'Morphology evaluation';
            app.TextArea_5 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_5.Editable = 'off';
            app.TextArea_6 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_6.Editable = 'off';
            app.MorphologyelementparametersDropDownLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.MorphologyelementparametersDropDownLabel.Text = 'Morphology element parameters';
            app.MorphologyelementparametersDropDown = uidropdown(app.MorphologyevaluationUIFigure);
            app.MorphologyelementparametersDropDown.Items = {'Young (BALB/c)', 'Adult (C57BL/6)', 'High bone mass (SOST -/-)', 'Low bone mass (GORAB)', 'User input...'};
            app.MorphologyelementparametersDropDown.ValueChangedFcn = createCallbackFcn(app, @MorphologyelementparametersDropDownValueChanged, true);
            app.MorphologyelementparametersDropDown.Value = 'Young (BALB/c)';
            app.TextArea_7 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_7.Editable = 'off';
            app.TextArea_8 = uitextarea(app.MorphologyevaluationUIFigure);
            app.TextArea_8.Editable = 'off';
            app.corticalthresholdTextAreaLabel_2 = uilabel(app.MorphologyevaluationUIFigure);
            app.corticalthresholdTextAreaLabel_2.Text = 'Day';
            app.corticalthresholdTextAreaLabel_3 = uilabel(app.MorphologyevaluationUIFigure);
            app.corticalthresholdTextAreaLabel_3.Text = 'in greyvalues';
            app.greyvalueTextArea = uilabel(app.MorphologyevaluationUIFigure);
            app.greyvalueTextArea.Enable = 'off';
            app.greyvalueTextArea.Text = 'in greyvalues';
            app.Acknowledgements = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.Acknowledgements.ButtonPushedFcn = createCallbackFcn(app, @AcknowledgementsPushed, true);
            app.Acknowledgements.Text = 'Acknowledgements';
            app.TestButton = uibutton(app.MorphologyevaluationUIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Tooltip = {'this will test your chosen morhpologic element parameters'};
            app.TestButton.Text = 'Test';
            app.ResolutionEditFieldLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.MorphologyevaluationUIFigure, 'numeric');
            app.ResolutionEditField.Limits = [0 Inf];
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditFieldLabel_2 = uilabel(app.MorphologyevaluationUIFigure);
            app.ResolutionEditFieldLabel_2.Text = 'um';
            app.OptimizedformousetibiaeLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.OptimizedformousetibiaeLabel.Text = 'Optimized for mouse tibiae';
            app.IfusingforcorticalboneanalysispleasereferenceLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.IfusingforcorticalboneanalysispleasereferenceLabel.Text = {'For cortical bone analysis, please reference: '; 'For trabecular bone analysis, please reference:'};
            app.BirkholdetalBone2014Label = uilabel(app.MorphologyevaluationUIFigure);
            app.BirkholdetalBone2014Label.Text = 'Birkhold et al., Bone, 2014';
            app.BirkholdetalBiomaterials2014Label = uilabel(app.MorphologyevaluationUIFigure);
            app.BirkholdetalBiomaterials2014Label.Text = 'Birkhold et al., Biomaterials, 2014';
            app.ThistooliscoveredundertheGNUGeneralPublicLicenseLabel = uilabel(app.MorphologyevaluationUIFigure);
            app.ThistooliscoveredundertheGNUGeneralPublicLicenseLabel.Text = {'This tool is covered under the GNU General Public License v3.0'; 'See terms here: https://www.gnu.org/licenses/gpl-3.0.html'};
            % Show the figure after all components are created
            app.MorphologyevaluationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_evaluation_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MorphologyevaluationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MorphologyevaluationUIFigure)
        end
    end
end